classdef TemperatureSensorDataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TemperatureSensorDataloggerv10UIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        YAxisMaximumCEditField         matlab.ui.control.NumericEditField
        YAxisMaximumCEditFieldLabel    matlab.ui.control.Label
        YAxisMinimumCEditField         matlab.ui.control.NumericEditField
        YAxisMinimumCEditFieldLabel    matlab.ui.control.Label
        PlotWindowsEditField           matlab.ui.control.NumericEditField
        PlotWindowsEditFieldLabel      matlab.ui.control.Label
        SampleIntervalsEditField       matlab.ui.control.NumericEditField
        SampleIntervalsEditFieldLabel  matlab.ui.control.Label
        RSSIEditField                  matlab.ui.control.NumericEditField
        RSSIEditFieldLabel             matlab.ui.control.Label
        ConnectionStatusLamp           matlab.ui.control.Lamp
        ConnectionStatusLampLabel      matlab.ui.control.Label
        SelectedDeviceEditField        matlab.ui.control.EditField
        SelectedDeviceLabel            matlab.ui.control.Label
        AvailableDevicesListBox        matlab.ui.control.ListBox
        AvailableDevicesLabel          matlab.ui.control.Label
        ConnectButton                  matlab.ui.control.Button
        FindBluetoothDevicesButton     matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        LogDataSwitch                  matlab.ui.control.Switch
        LogDataSwitchLabel             matlab.ui.control.Label
        FiletypeDropDown               matlab.ui.control.DropDown
        FiletypeDropDownLabel          matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField             matlab.ui.control.EditField
        DirectoryEditFieldLabel        matlab.ui.control.Label
        SetDataloggingDirectoryButton  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Devices = []; % Variable that contains a table of available devices for the sensor to connect to
        isConnected = false; % State variable that describes if a device is connected to the interface 
        BLEDevice = []; % Description
        TemperatureCharacteristic
        DataloggingTimer % Description
        SingalStrength % Description
        LogFileDir % Description
        LogFileName % Description
        LogFileFullfile % Description
        LogFileExtension % Description
        isLoggingData = false; % Description
        LogFileIndex = 1; % Description
        plotWindowWidth = seconds(90); % Description
    end
    
    methods (Access = private)
        
        function updatePlot(app,~,event) % Updates Plot Data
            tnow = datetime("now","TimeZone","local");

            % Check to see if the device is still connected
            try
                rawBytes = read(app.TemperatureCharacteristic);
            catch
                warning('No New Data Recieved from Temperature Sensor Module');
                ConnectButtonPushed(app,event);
            end
            Tnow = bytes2Temperature(app,rawBytes);
            XLowerLimit = app.UIAxes.XLim(1);
            XUpperLimit = app.UIAxes.XLim(2);
            if(tnow > XUpperLimit)
                app.UIAxes.XLim = [XLowerLimit + app.plotWindowWidth*0.15, XUpperLimit + app.plotWindowWidth*0.15];
            end

            % If datalogging is active, write data to file
            if(app.isLoggingData)
                app.appendToDatafile(string(tnow),Tnow);
            end
            plot(app.UIAxes,tnow,Tnow,'b.','LineWidth',2);
        end
        
        function updateFilepath(app)
            % Update Fullfile Property
            app.LogFileFullfile = fullfile(app.LogFileDir,strcat(app.LogFileName,'_',num2str(app.LogFileIndex),app.LogFileExtension));

            % Update UI Elemetns 
            app.DirectoryEditField.Value = app.LogFileFullfile;
        end

        function appendToDatafile(app,t,T)
            % Check if the datafile exists at the fullfile location
                if(isfile(app.LogFileFullfile))
                else
                    % Create Header Line and File
                    writematrix(["Time","Temperature"],app.LogFileFullfile);
                end
                % Append Time and Temperature to Datafile
                try
                    writematrix([t T],app.LogFileFullfile,"WriteMode","append");
                catch Error
                    warning(['Unable to Append to Datafile: ', Error.message]);
                end
        end
        
        function T = bytes2Temperature(app,bytes)
            bytes = uint8(bytes);
            byte1 = bytes(1);
            byte2 = bytes(2);
            binary_1 = dec2bin(byte1,8);
            binary_2 = dec2bin(byte2,8);
            if(binary_1(1) == '1')
                T = bin2dec([binary_1 binary_2] + 1)*-0.0078125;
            else
                T = bin2dec([binary_1 binary_2])*0.0078125;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot(app.UIAxes, datetime('now', 'TimeZone', 'local', 'Format', 'HH:mm:ss'), 50);
           
            % Set x limits and ticks
            t = datetime('now', 'TimeZone', 'local', 'Format', 'HH:mm:ss') + seconds(1:60);
            xLL = t(1) - seconds(5);
            xUL = t(length(t));
            app.UIAxes.XLim = [xLL xUL];
            app.UIAxes.XAxis.TickLabelFormat = 'HH:mm:ss';
            
            % Set y limits and ticks
            app.UIAxes.YLim = [20 35];
            app.YAxisMinimumCEditField.Limits = [-inf,app.UIAxes.YLim(2)];
            app.YAxisMaximumCEditField.Limits = [app.UIAxes.YLim(1),inf];
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'on');
            
            % Create timer object
            app.DataloggingTimer = timer(...
            'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
            'Period', 10, ...
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.updatePlot);   % Callback that runs every period

            % Initialize Datafile location and name
            app.LogFileDir = cd;
            app.LogFileName = "TestData";
            app.LogFileExtension = '.csv';
            app.FilenameEditField.Value = app.LogFileName;
            app.LogFileFullfile = fullfile(app.LogFileDir,strcat(app.LogFileName,'_1',app.LogFileExtension));
            app.updateFilepath();

        end

        % Button pushed function: FindBluetoothDevicesButton
        function FindBluetoothDevicesButtonPushed(app, event)
            % Clear the List Box
            app.AvailableDevicesListBox.Items = {};
            % Find list of BLE devices that have the specified name
            try
            app.Devices = blelist("Name","Temperature Sensor");
            % Update the list box
            app.AvailableDevicesListBox.Items = app.Devices.Address;
            app.SingalStrength = app.Devices.RSSI;
            app.AvailableDevicesListBox.Enable = 'on';
            catch
                app.AvailableDevicesListBox.Items = "No Devices Available";
                app.AvailableDevicesListBox.Enable = 'off';
            end
        end

        % Value changed function: AvailableDevicesListBox
        function AvailableDevicesListBoxValueChanged(app, event)
            app.SelectedDeviceEditField.Value = app.AvailableDevicesListBox.Value;
            if(~isempty(app.SelectedDeviceEditField.Value))
                app.ConnectButton.Enable = 'on';
            else
                app.ConnectButton.Enable = 'off';
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.ConnectButton.Enable = 'off';
            if(~app.isConnected)
                Address = app.SelectedDeviceEditField.Value;
                app.BLEDevice = ble(Address);
                app.TemperatureCharacteristic = characteristic(app.BLEDevice,"181A","2A6E");
                app.RSSIEditField.Value = app.SingalStrength(1);
                app.ConnectionStatusLamp.Color = [0 1 0];
                app.ConnectButton.Text = "Disconnect";
                if strcmp(app.DataloggingTimer.Running, 'off')
                    % Start timer
                    start(app.DataloggingTimer);
                end
            end

            if(app.isConnected)
                app.BLEDevice = []; 
                app.TemperatureCharacteristic = [];
                app.ConnectionStatusLamp.Color = [1 0 0];
                app.ConnectButton.Text = "Connect";
                stop(app.DataloggingTimer);
                pause(5);
                FindBluetoothDevicesButtonPushed(app, event);
                app.SelectedDeviceEditField.Value = '';
                app.RSSIEditField.Value = 0;
            end
            app.isConnected = ~app.isConnected;
            app.ConnectButton.Enable = 'on';
        end

        % Close request function: TemperatureSensorDataloggerv10UIFigure
        function TemperatureSensorDataloggerv10UIFigureCloseRequest(app, event)
            stop(app.DataloggingTimer);
            delete(app.DataloggingTimer);
            delete(app);
            
        end

        % Value changed function: SampleIntervalsEditField
        function SampleIntervalsEditFieldValueChanged(app, event)
            if strcmp(app.DataloggingTimer.Running, 'on')
                stop(app.DataloggingTimer);
            end
                app.DataloggingTimer.Period = app.SampleIntervalsEditField.Value;
                start(app.DataloggingTimer);
        end

        % Value changed function: YAxisMinimumCEditField
        function YAxisMinimumCEditFieldValueChanged(app, event)
            app.UIAxes.YLim(1) = app.YAxisMinimumCEditField.Value;
            app.UIAxes.YLim(2) = app.YAxisMaximumCEditField.Value;
            app.YAxisMinimumCEditField.Limits = [-inf,app.UIAxes.YLim(2) - 0.1];
            app.YAxisMaximumCEditField.Limits = [app.UIAxes.YLim(1) + 0.1,inf];
        end

        % Value changed function: YAxisMaximumCEditField
        function YAxisMaximumCEditFieldValueChanged(app, event)
            app.UIAxes.YLim(1) = app.YAxisMinimumCEditField.Value;
            app.UIAxes.YLim(2) = app.YAxisMaximumCEditField.Value;
            app.YAxisMinimumCEditField.Limits = [-inf,app.UIAxes.YLim(2) - 0.1];
            app.YAxisMaximumCEditField.Limits = [app.UIAxes.YLim(1) + 0.1,inf];
        end

        % Button pushed function: SetDataloggingDirectoryButton
        function SetDataloggingDirectoryButtonPushed(app, event)
            app.LogFileDir = uigetdir(cd,"Select a file directory for datalogging");
            app.updateFilepath();
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            app.LogFileName = app.FilenameEditField.Value;
            app.updateFilepath();
        end

        % Value changed function: FiletypeDropDown
        function FiletypeDropDownValueChanged(app, event)
            app.LogFileExtension = app.FiletypeDropDown.Value;
            app.updateFilepath();
        end

        % Value changed function: LogDataSwitch
        function LogDataSwitchValueChanged(app, event)
            app.isLoggingData = app.LogDataSwitch.Value;

            % Check directory for identical file names, then append _X and
            % updated the filepath property
            Filenames = dir(fullfile(app.LogFileDir,strcat(app.LogFileName,'_*',app.LogFileExtension)));
            app.LogFileIndex = 1;
            if (~isempty(Filenames))
                % Iterate through filenames and find already used indeces
                for iter = 1:length(Filenames)
                    intIndexStart = strfind(Filenames(iter).name,'_') + 1;
                    intIndexEnd = strfind(Filenames(iter).name,app.LogFileExtension) - 1;
                    if (intIndexEnd == intIndexStart) 
                        Indeces(iter) = str2double(Filenames(iter).name(intIndexStart));
                    else
                        Indeces(iter) = str2double(Filenames(iter).name(intIndexStart:intIndexEnd));
                    end
                end
                % Find the lowest non-used index
                i = 1:length(Indeces);
                isSequenced = (Indeces ~= i);
                if sum(isSequenced) ~= 0
                    newIndex = find(isSequenced);
                    app.LogFileIndex = newIndex(1);
                else
                    app.LogFileIndex = iter + 1;
                end
            end
            app.updateFilepath();
            % Check that a Bluetooth device is connnected
            if (app.isConnected)
                if (app.isLoggingData)
                    app.updateFilepath();
                    app.FilenameEditField.Editable = 'off';
                    app.FilenameEditField.Enable = 'off';
                    app.FiletypeDropDown.Editable = 'off';
                    app.FiletypeDropDown.Enable = 'off';
                    app.SetDataloggingDirectoryButton.Enable = 'off';
                end
                if (~app.isLoggingData)
                    app.FilenameEditField.Editable = 'on';
                    app.FilenameEditField.Enable = 'on';
                    app.FiletypeDropDown.Editable = 'on';
                    app.FiletypeDropDown.Enable = 'on';
                    app.SetDataloggingDirectoryButton.Enable = 'on';
                end
            else
                warning('A Bluetooth device must be connected to begin logging data');
                app.isLoggingData = ~app.isLoggingData;
                app.LogDataSwitch.Value = 0;
                return;
            end

            
        end

        % Value changed function: PlotWindowsEditField
        function PlotWindowsEditFieldValueChanged(app, event)
            app.plotWindowWidth = seconds(app.PlotWindowsEditField.Value);
            currentTime = datetime("now","TimeZone","local");
            lowerLim = currentTime;
            upperLim = currentTime + app.plotWindowWidth;
            app.UIAxes.XLim = [lowerLim, upperLim];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TemperatureSensorDataloggerv10UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {488, 488};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {263, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TemperatureSensorDataloggerv10UIFigure = uifigure('Visible', 'off');
            app.TemperatureSensorDataloggerv10UIFigure.Name = 'Temperature Sensor Datalogger v1.0';
            app.TemperatureSensorDataloggerv10UIFigure.CloseRequestFcn = createCallbackFcn(app, @TemperatureSensorDataloggerv10UIFigureCloseRequest, true);
            app.TemperatureSensorDataloggerv10UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TemperatureSensorDataloggerv10UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.FindBluetoothDevicesButton = uibutton(app.GridLayout3, 'push');
            app.FindBluetoothDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @FindBluetoothDevicesButtonPushed, true);
            app.FindBluetoothDevicesButton.Text = 'Find Bluetooth Devices';
            app.ConnectButton = uibutton(app.GridLayout3, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.AvailableDevicesLabel = uilabel(app.GridLayout3);
            app.AvailableDevicesLabel.Text = 'Available Devices:';
            app.AvailableDevicesListBox = uilistbox(app.GridLayout3);
            app.AvailableDevicesListBox.Items = {};
            app.AvailableDevicesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableDevicesListBoxValueChanged, true);
            app.AvailableDevicesListBox.Enable = 'off';
            app.AvailableDevicesListBox.Value = {};
            app.SelectedDeviceLabel = uilabel(app.GridLayout3);
            app.SelectedDeviceLabel.Text = 'Selected Device:';
            app.SelectedDeviceEditField = uieditfield(app.GridLayout3, 'text');
            app.SelectedDeviceEditField.Editable = 'off';
            app.SelectedDeviceEditField.Placeholder = 'No Device Selected';
            app.ConnectionStatusLampLabel = uilabel(app.GridLayout3);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.GridLayout3);
            app.RSSIEditFieldLabel = uilabel(app.GridLayout3);
            app.RSSIEditFieldLabel.Text = 'RSSI';
            app.RSSIEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RSSIEditField.Editable = 'off';
            app.SampleIntervalsEditFieldLabel = uilabel(app.GridLayout3);
            app.SampleIntervalsEditFieldLabel.Text = 'Sample Interval [s]';
            app.SampleIntervalsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SampleIntervalsEditField.Limits = [1 10000];
            app.SampleIntervalsEditField.RoundFractionalValues = 'on';
            app.SampleIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @SampleIntervalsEditFieldValueChanged, true);
            app.SampleIntervalsEditField.Value = 10;
            app.PlotWindowsEditFieldLabel = uilabel(app.GridLayout3);
            app.PlotWindowsEditFieldLabel.Text = 'Plot Window [s]';
            app.PlotWindowsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PlotWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @PlotWindowsEditFieldValueChanged, true);
            app.PlotWindowsEditField.Value = 60;
            app.YAxisMinimumCEditFieldLabel = uilabel(app.GridLayout3);
            app.YAxisMinimumCEditFieldLabel.Text = 'Y-Axis Minimum [C]';
            app.YAxisMinimumCEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YAxisMinimumCEditField.ValueChangedFcn = createCallbackFcn(app, @YAxisMinimumCEditFieldValueChanged, true);
            app.YAxisMinimumCEditField.Value = 20;
            app.YAxisMaximumCEditFieldLabel = uilabel(app.GridLayout3);
            app.YAxisMaximumCEditFieldLabel.Text = 'Y-Axis Maximum [C]';
            app.YAxisMaximumCEditField = uieditfield(app.GridLayout3, 'numeric');
            app.YAxisMaximumCEditField.ValueChangedFcn = createCallbackFcn(app, @YAxisMaximumCEditFieldValueChanged, true);
            app.YAxisMaximumCEditField.Value = 35;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Temperature vs. Time')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Temperature [C]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [20 40];
            app.SetDataloggingDirectoryButton = uibutton(app.GridLayout2, 'push');
            app.SetDataloggingDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetDataloggingDirectoryButtonPushed, true);
            app.SetDataloggingDirectoryButton.Text = 'Set Datalogging Directory:';
            app.DirectoryEditFieldLabel = uilabel(app.GridLayout2);
            app.DirectoryEditFieldLabel.Text = 'Directory:';
            app.DirectoryEditField = uieditfield(app.GridLayout2, 'text');
            app.DirectoryEditField.Editable = 'off';
            app.FilenameEditFieldLabel = uilabel(app.GridLayout2);
            app.FilenameEditFieldLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.GridLayout2, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FiletypeDropDownLabel = uilabel(app.GridLayout2);
            app.FiletypeDropDownLabel.Text = 'Filetype:';
            app.FiletypeDropDown = uidropdown(app.GridLayout2);
            app.FiletypeDropDown.Items = {'.csv'};
            app.FiletypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletypeDropDownValueChanged, true);
            app.FiletypeDropDown.Value = '.csv';
            app.LogDataSwitchLabel = uilabel(app.GridLayout2);
            app.LogDataSwitchLabel.Text = 'Log Data';
            app.LogDataSwitch = uiswitch(app.GridLayout2, 'slider');
            app.LogDataSwitch.ItemsData = [0 1];
            app.LogDataSwitch.ValueChangedFcn = createCallbackFcn(app, @LogDataSwitchValueChanged, true);
            app.LogDataSwitch.Value = 0;
            % Show the figure after all components are created
            app.TemperatureSensorDataloggerv10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TemperatureSensorDataLogger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TemperatureSensorDataloggerv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TemperatureSensorDataloggerv10UIFigure)
        end
    end
end